classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Robot_toolboxUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        RobotconfigurationPanel         matlab.ui.container.Panel
        GroupDropDownLabel              matlab.ui.control.Label
        GroupDropDown                   matlab.ui.control.DropDown
        ResetconfigurationButton        matlab.ui.control.Button
        ShowconfigurationButton         matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        LoadfunctionsPanel              matlab.ui.container.Panel
        RobotFunction                   matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        LoadRobotButton                 matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        LoadWhitelistButton             matlab.ui.control.Button
        LoadBoundsButton                matlab.ui.control.Button
        LoadrobotkinematicsPanel        matlab.ui.container.Panel
        KinematicsfolderEditFieldLabel  matlab.ui.control.Label
        KinematicsfolderEditField       matlab.ui.control.EditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        TXTButton                       matlab.ui.control.RadioButton
        MATButton                       matlab.ui.control.RadioButton
        LoadKinematicsButton            matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        TXTTab                          matlab.ui.container.Tab
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        MATTab                          matlab.ui.container.Tab
        TypeButtonGroup                 matlab.ui.container.ButtonGroup
        NoneButton                      matlab.ui.control.RadioButton
        MinButton                       matlab.ui.control.RadioButton
        MaxButton                       matlab.ui.control.RadioButton
        MedianButton                    matlab.ui.control.RadioButton
        PertSpinnerLabel                matlab.ui.control.Label
        PertSpinner                     matlab.ui.control.Spinner
        RepSpinnerLabel                 matlab.ui.control.Label
        RepSpinner                      matlab.ui.control.Spinner
        LoaddatasetPanel                matlab.ui.container.Panel
        DatasetparametersTextAreaLabel  matlab.ui.control.Label
        DatasetparametersTextArea       matlab.ui.control.TextArea
        DatasetfunctionEditFieldLabel   matlab.ui.control.Label
        DatasetfunctionEditField        matlab.ui.control.EditField
        LoadDatasetButton               matlab.ui.control.Button
        VisualisationPanel              matlab.ui.container.Panel
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        CalibrationoptionsPanel         matlab.ui.container.Panel
        ApproachListBoxLabel            matlab.ui.control.Label
        ApproachListBox                 matlab.ui.control.ListBox
        jointTypesLabel                 matlab.ui.control.Label
        jointTypesListBox               matlab.ui.control.ListBox
        ChainsListBoxLabel              matlab.ui.control.Label
        ChainsListBox                   matlab.ui.control.ListBox
        CalibrationsettingsPanel_4      matlab.ui.container.Panel
        RuncalibrationButton            matlab.ui.control.Button
        SavejacobiansCheckBox           matlab.ui.control.CheckBox
        SavedatasetCheckBox             matlab.ui.control.CheckBox
        SavevarsCheckBox                matlab.ui.control.CheckBox
        SavefoldernameEditFieldLabel    matlab.ui.control.Label
        SavefoldernameEditField         matlab.ui.control.EditField
        TextArea                        matlab.ui.control.TextArea
        ConfigTab                       matlab.ui.container.Tab
        CalibrationoptionsPanel_2       matlab.ui.container.Panel
        ApproachListBox_3Label          matlab.ui.control.Label
        ApproachListBox_3               matlab.ui.control.ListBox
        jointTypesListBox_3Label        matlab.ui.control.Label
        jointTypesListBox_3             matlab.ui.control.ListBox
        ChainsListBox_3Label            matlab.ui.control.Label
        ChainsListBox_3                 matlab.ui.control.ListBox
        Panel                           matlab.ui.container.Panel
        LoadConfigfileButton            matlab.ui.control.Button
        SaveConfigfileButton            matlab.ui.control.Button
        ReturntomainButton              matlab.ui.control.Button
        PerturbationoptionsPanel        matlab.ui.container.Panel
        UITable2_3                      matlab.ui.control.Table
        OptimoptionsPanel               matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        OptimizationoptionsPanel        matlab.ui.container.Panel
        UITable2_2                      matlab.ui.control.Table
        RobotTab                        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        JointsTab                       matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        LoadRobotfileButton             matlab.ui.control.Button
        SaveRobotfileButton             matlab.ui.control.Button
        ReturntomainButton_2            matlab.ui.control.Button
        NexttabButton                   matlab.ui.control.Button
        JointstructurePanel             matlab.ui.container.Panel
        UITable4                        matlab.ui.control.Table
        UIAxes2                         matlab.ui.control.UIAxes
        RobotnameEditFieldLabel         matlab.ui.control.Label
        RobotnameEditField              matlab.ui.control.EditField
        H0transformationmatrixPanel     matlab.ui.container.Panel
        H0mat                           matlab.ui.control.Table
        KinematicsTab                   matlab.ui.container.Tab
        KinematicsparametersPanel       matlab.ui.container.Panel
        UITable5                        matlab.ui.control.Table
        Panel_3                         matlab.ui.container.Panel
        LoadRobotfileButton_2           matlab.ui.control.Button
        SaveRobotfileButton_2           matlab.ui.control.Button
        ReturntomainButton_3            matlab.ui.control.Button
        NexttabButton_2                 matlab.ui.control.Button
        RobotdefaultconfigurationPanel  matlab.ui.container.Panel
        UITable_2                       matlab.ui.control.Table
        GroupDropDown_2Label            matlab.ui.control.Label
        GroupDropDown_2                 matlab.ui.control.DropDown
        WhitelistBoundsTab              matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        LoadRobotfileButton_3           matlab.ui.control.Button
        SaveRobotfileButton_3           matlab.ui.control.Button
        ReturntomainButton_4            matlab.ui.control.Button
        NexttabButton_3                 matlab.ui.control.Button
        GroupDropDown_3Label            matlab.ui.control.Label
        GroupDropDown_3                 matlab.ui.control.DropDown
        WhitelistPanel                  matlab.ui.container.Panel
        UITable5_2                      matlab.ui.control.Table
        SavewhitelistButton             matlab.ui.control.Button
        BoundsPanel                     matlab.ui.container.Panel
        UITable5_3                      matlab.ui.control.Table
        SaveboundsButton                matlab.ui.control.Button
        CameraseyesTab                  matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        LoadRobotfileButton_4           matlab.ui.control.Button
        SaveRobotfileButton_4           matlab.ui.control.Button
        ReturntomainButton_5            matlab.ui.control.Button
        NexttabButton_4                 matlab.ui.control.Button
        LefteyePanel                    matlab.ui.container.Panel
        DistortioncoefficientsPanel     matlab.ui.container.Panel
        tCoef1                          matlab.ui.control.Table
        tangentialLabel                 matlab.ui.control.Label
        radialLabel                     matlab.ui.control.Label
        rCoef1                          matlab.ui.control.Table
        CameramatrixPanel               matlab.ui.container.Panel
        camMatrix1                      matlab.ui.control.Table
        RighteyePanel                   matlab.ui.container.Panel
        DistortioncoefficientsPanel_2   matlab.ui.container.Panel
        tCoef2                          matlab.ui.control.Table
        tangentialLabel_2               matlab.ui.control.Label
        radialLabel_2                   matlab.ui.control.Label
        rCoef2                          matlab.ui.control.Table
        CameramatrixPanel_2             matlab.ui.container.Panel
        camMatrix2                      matlab.ui.control.Table
        AdditionaleyePanel              matlab.ui.container.Panel
        DistortioncoefficientsPanel_3   matlab.ui.container.Panel
        tCoef3                          matlab.ui.control.Table
        tangentialLabel_3               matlab.ui.control.Label
        radialLabel_3                   matlab.ui.control.Label
        rCoef3                          matlab.ui.control.Table
        CameramatrixPanel_3             matlab.ui.container.Panel
        camMatrix3                      matlab.ui.control.Table
        DatasetTab                      matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        LoadDatasetfileButton           matlab.ui.control.Button
        SaveDatasetfileButton           matlab.ui.control.Button
        ReturntomainButton_6            matlab.ui.control.Button
        DatasetinfoPanel                matlab.ui.container.Panel
        DatasetnameEditFieldLabel       matlab.ui.control.Label
        DatasetnameEditField            matlab.ui.control.EditField
        DatasetidEditFieldLabel         matlab.ui.control.Label
        DatasetidEditField              matlab.ui.control.NumericEditField
        DatasettypeDropDownLabel        matlab.ui.control.Label
        DatasettypeDropDown             matlab.ui.control.DropDown
        JointsPanel                     matlab.ui.container.Panel
        JointsDropDown                  matlab.ui.control.DropDown
        EditField_5                     matlab.ui.control.NumericEditField
        DataPanel                       matlab.ui.container.Panel
        DatasetData                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        Robot % Description
        Config % Description
        jointTypesListBoxProp
        ChainsListBoxProp
        ChainsListBox_3Prop
        jointTypesListBox_3Prop
        ApproachListBoxProp
        ApproachListBox_3Prop
        Whitelist
        Bounds
    end
    methods (Access = private)
        
          function setPromptFcn(app,jTextArea,eventData,newPrompt)
           % Prevent overlapping reentry due to prompt replacement
          persistent inProgress
          if isempty(inProgress)
            inProgress = 1;  %#ok unused
          else
            return;
          end
         
          try
            % *** Prompt modification code goes here ***
            cwText = char(jTextArea.getText);
            app.TextArea.Value = cwText;
            % force prompt-change callback to fizzle-out...
            pause(0.02);
          catch
            % Never mind - ignore errors...
          end
         
          % Enable new callbacks now that the prompt has been modified
          inProgress = [];
         
        end  % setPromptFcn
    end
    methods (Access = public)
        function  changeListBox(app, field, toChange)
            for item=reshape(toChange, 1, [])
                app.(item{1}).Value = {};
            end
            for item=fieldnames(app.Config.(field))'
                item=item{1};
                if app.Config.(field).(item)
                     for item_=reshape(toChange, 1, [])
                        app.(item_{1}).Value{end+1} = item;
                     end
                end
            end
            for item=reshape(toChange, 1, [])
                app.([item{1}, 'Prop']) = app.(item_{1}).Value;
            end
        end
        function listBoxValueChanged(app, box, property)
            val = app.(box).Value;
            if any(contains(app.(property), val))
                app.(property)(contains(app.(property), val))=[];
            else
                app.(property){end+1} = val{1};
            end
            app.(box).Value = app.(property);
        end
        function configChangeTable(app, values, table)
            table.Data = [];
            temp = [];
            for item=fieldnames(values)'
                item=item{1};
                if isstruct(values.(item))
                    for t=fieldnames(values.(item))'
                        t=t{1};
                        temp = [temp; {[item,'.',t], mat2str(values.(item).(t))}];
                    end
                elseif isstr(values.(item))
                    temp = [temp; {item, values.(item)}];
                else
                    temp = [temp; {item, mat2str(values.(item))}];
                end
            end
            table.Data = temp;
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            %view(app.UIAxes,[-5 2 5]);
            try
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
            catch
                warndlg('fatal error');
            end
            setPromptFcn(app,jTextArea);
            app.Config = struct('options',[], 'chains',[], 'approach',[], 'jointTypes',[], 'optim',[], 'pert', []);
            app.H0mat.Data = cell(4,4);
            app.H0mat.ColumnWidth = {40,40,40,40};
            app.H0mat.ColumnEditable = true;
            app.camMatrix1.Data = cell(3,3);
            app.camMatrix1.ColumnEditable = true;
            app.camMatrix1.ColumnWidth = {89,89,89,89};
            app.camMatrix2.Data = cell(3,3);
            app.camMatrix2.ColumnEditable = true;
            app.camMatrix2.ColumnWidth = {89,89,89,89};
            app.camMatrix3.Data = cell(3,3);
            app.camMatrix3.ColumnEditable = true;
            app.camMatrix3.ColumnWidth = {89,89,89,89};
            app.rCoef1.Data = cell(1,6);
            app.rCoef1.ColumnEditable = true;
            app.rCoef1.ColumnWidth = {40,40,40,40,40,40};
            app.tCoef1.Data = cell(1,2);
            app.tCoef1.ColumnEditable = true;
            app.tCoef1.ColumnWidth = {103,103};
            app.rCoef2.Data = cell(1,6);
            app.rCoef2.ColumnEditable = true;
            app.rCoef2.ColumnWidth = {40,40,40,40,40,40};
            app.tCoef2.Data = cell(1,2);
            app.tCoef2.ColumnEditable = true;
            app.tCoef2.ColumnWidth = {103,103};
            app.rCoef3.Data = cell(1,6);
            app.rCoef3.ColumnEditable = true;
            app.rCoef3.ColumnWidth = {40,40,40,40,40,40};
            app.tCoef3.Data = cell(1,2);
            app.tCoef3.ColumnEditable = true;
            app.tCoef3.ColumnWidth = {103,103};
            app.DatasetData.ColumnName = {'Pose','Frame','Frame2','Point','RefPoint','Camera','Joints rightArm', 'Joints leftArm', ...
            'Joints torso', 'Joints head', 'Joints leftEye', 'Joints rightEye', 'Joints leftLeg', 'Joints rightLeg', 'Joints leftIndex', ...
            'Joints rightIndex', 'Joints leftThumb', 'Joints rightThumb', 'Joints leftMiddle', 'Joints rightMiddle'};
            app.jointTypesListBoxProp = app.jointTypesListBox.Value;
            app.jointTypesListBox_3Prop = app.jointTypesListBox_3.Value;
            app.ChainsListBoxProp = app.ChainsListBox.Value;
            app.ChainsListBox_3Prop = app.ChainsListBox_3.Value;
            app.ApproachListBoxProp = app.ApproachListBox.Value;
            app.ApproachListBox_3Prop = app.ApproachListBox_3.Value;
            app.Whitelist = [];
            app.Bounds = [];
            app.Robot = [];
        end

        % Button pushed function: LoadRobotButton, 
        % LoadRobotfileButton
        function LoadRobotButtonPushed(app, event)
            app.Whitelist = [];
            app.Bounds = [];
            app.Robot = Robot(app.RobotFunction.Value);
            app.RobotnameEditField.Value = app.Robot.name;
            app.H0mat.Data = app.Robot.structure.H0;
            axes(app.UIAxes2);
            app.Robot.showGraphModel;
            app.UITable4.Data = app.Robot.jointsStructure;
            app.GroupDropDown_2ValueChanged(event);
            app.GroupDropDown_3ValueChanged(event);
            app.GroupDropDown_2.Items = fieldnames(app.Robot.structure.DH);
            app.GroupDropDown_3.Items = fieldnames(app.Robot.structure.DH);
            
%             axes(app.UIAxes);
%             app.Robot.showModel('figName', 'Robot_toolbox');
        end

        % Value changed function: GroupDropDown_2
        function GroupDropDown_2ValueChanged(app, event)
            app.UITable5.Data = [];
            if size(app.Robot.structure.DH.(app.GroupDropDown_2.Value), 2) == 4
                app.UITable5.Data(:, [1,2,4,6]) = app.Robot.structure.DH.(app.GroupDropDown_2.Value);
            else
                app.UITable5.Data = app.Robot.structure.DH.(app.GroupDropDown_2.Value);
            end
            
        end

        % Value changed function: GroupDropDown_3
        function GroupDropDown_3ValueChanged(app, event)
            app.UITable5_2.Data = [];
            app.UITable5_3.Data = [];
            if ~isempty(app.Whitelist)
                if size(app.Whitelist.(app.GroupDropDown_3.Value), 2) == 4
                    app.UITable5_2.Data(:, [1,2,4,6]) = app.Whitelist.(app.GroupDropDown_3.Value);
                else
                    app.UITable5_2.Data = app.Whitelist.(app.GroupDropDown_3.Value);
                end
            elseif ~isempty(app.Robot)
                if size(app.Robot.structure.WL.(app.GroupDropDown_3.Value), 2) == 4
                    app.UITable5_2.Data(:, [1,2,4,6]) = app.Robot.structure.DH.(app.GroupDropDown_3.Value);
                else
                    app.UITable5_2.Data = app.Robot.structure.WL.(app.GroupDropDown_3.Value);
                end
            end
            if ~isempty(app.Bounds)
                if size(app.Bounds.(app.GroupDropDown_3.Value), 2) == 4
                    app.UITable5_3.Data(:, [1,2,4,6]) = app.Bounds.(app.GroupDropDown_3.Value);
                else
                    app.UITable5_3.Data = app.Bounds.(app.GroupDropDown_3.Value);
                end
            elseif ~isempty(app.Robot)
                joints = app.Robot.findJointByGroup(app.GroupDropDown_3.Value);
                temp = [];
                for joint=joints
                    joint=joint{1};
                    if length(app.Robot.structure.bounds.(joint.type))==4
                        temp_ = nan(1,6);
                        temp_([1,2,4,6]) = app.Robot.structure.bounds.(joint.type);
                        temp = [temp; temp_];
                    else
                        temp = [temp;app.Robot.structure.bounds.(joint.type)];
                    end
                end
                app.UITable5_3.Data = temp;
            end
            
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            func=str2func(app.EditField_2.Value);
            [app.Config.options, app.Config.chains, app.Config.approach, app.Config.jointTypes, app.Config.optim, app.Config.pert] = func();
            
            app.configChangeTable(app.Config.options, app.UITable2_2);
            app.configChangeTable(app.Config.optim, app.UITable2);
            app.UITable2_3.Data = [];
            temp = [];
            for item=reshape(app.Config.pert, 1, [])
                item = item{1};
                if length(item) == 4
                    temp_ = nan(1,6);
                    temp_([1,2,4,6]) = item;
                    item = temp_;
                end
                temp = [temp; item];
            end
            app.UITable2_3.Data = temp;
            app.changeListBox('chains', {'ChainsListBox', 'ChainsListBox_3'});
            app.changeListBox('jointTypes', {'jointTypesListBox_3', 'jointTypesListBox'});
            app.changeListBox('approach', {'ApproachListBox', 'ApproachListBox_3'});
        end

        % Value changed function: ApproachListBox, 
        % ApproachListBox_3, ChainsListBox, ChainsListBox_3, 
        % jointTypesListBox, jointTypesListBox_3
        function ListBoxValueChangedCallback(app, event)
            box = event.Source.Tag;
            app.listBoxValueChanged(box, [box,'Prop']);
        end

        % Button pushed function: LoadWhitelistButton
        function LoadWhitelistButtonPushed(app, event)
            func = str2func(app.EditField_3.Value);
            app.Whitelist = func();
            app.GroupDropDown_3ValueChanged(event);
            app.GroupDropDown_3.Items = fieldnames(app.Robot.structure.DH);
        end

        % Button pushed function: LoadBoundsButton
        function LoadBoundsButtonPushed(app, event)
            func = str2func(app.EditField_4.Value);
            app.Bounds = func();
            app.GroupDropDown_3ValueChanged(event);
            app.GroupDropDown_3.Items = fieldnames(app.Robot.structure.DH);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Robot_toolboxUIFigure = uifigure('Visible', 'off');
            app.Robot_toolboxUIFigure.Name = 'Robot_toolbox';
            app.TabGroup = uitabgroup(app.Robot_toolboxUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.RobotconfigurationPanel = uipanel(app.MainTab);
            app.RobotconfigurationPanel.Title = 'Robot configuration';
            app.GroupDropDownLabel = uilabel(app.RobotconfigurationPanel);
            app.GroupDropDownLabel.Text = 'Group';
            app.GroupDropDown = uidropdown(app.RobotconfigurationPanel);
            app.GroupDropDown.Items = {'rightArm', 'leftArm', 'torso', 'head', 'leftEye', 'rightEye', 'leftLeg', 'rightLeg', 'leftIndex', 'rightIndex', 'leftThumb', 'rightThumb', 'leftMiddle', 'rightMiddle'};
            app.GroupDropDown.Value = 'rightArm';
            app.ResetconfigurationButton = uibutton(app.RobotconfigurationPanel, 'push');
            app.ResetconfigurationButton.Text = 'Reset configuration';
            app.ShowconfigurationButton = uibutton(app.RobotconfigurationPanel, 'push');
            app.ShowconfigurationButton.Text = 'Show configuration';
            app.UITable = uitable(app.RobotconfigurationPanel);
            app.UITable.ColumnName = {'Joint 1'; 'Joint 2'; 'Joint 3'; 'Joint 4'; 'Joint 5'; 'Joint 6'};
            app.UITable.RowName = {};
            app.LoadfunctionsPanel = uipanel(app.MainTab);
            app.LoadfunctionsPanel.Title = 'Load functions';
            app.RobotFunction = uieditfield(app.LoadfunctionsPanel, 'text');
            app.EditField_2 = uieditfield(app.LoadfunctionsPanel, 'text');
            app.EditField_3 = uieditfield(app.LoadfunctionsPanel, 'text');
            app.EditField_4 = uieditfield(app.LoadfunctionsPanel, 'text');
            app.LoadRobotButton = uibutton(app.LoadfunctionsPanel, 'push');
            app.LoadRobotButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRobotButtonPushed, true);
            app.LoadRobotButton.Text = 'Load Robot';
            app.LoadConfigButton = uibutton(app.LoadfunctionsPanel, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.LoadWhitelistButton = uibutton(app.LoadfunctionsPanel, 'push');
            app.LoadWhitelistButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWhitelistButtonPushed, true);
            app.LoadWhitelistButton.Text = 'Load Whitelist';
            app.LoadBoundsButton = uibutton(app.LoadfunctionsPanel, 'push');
            app.LoadBoundsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBoundsButtonPushed, true);
            app.LoadBoundsButton.Text = 'Load Bounds';
            app.LoadrobotkinematicsPanel = uipanel(app.MainTab);
            app.LoadrobotkinematicsPanel.Title = 'Load robot kinematics';
            app.KinematicsfolderEditFieldLabel = uilabel(app.LoadrobotkinematicsPanel);
            app.KinematicsfolderEditFieldLabel.Text = {'Kinematics '; 'folder'};
            app.KinematicsfolderEditField = uieditfield(app.LoadrobotkinematicsPanel, 'text');
            app.ButtonGroup = uibuttongroup(app.LoadrobotkinematicsPanel);
            app.TXTButton = uiradiobutton(app.ButtonGroup);
            app.TXTButton.Text = 'TXT';
            app.TXTButton.Value = true;
            app.MATButton = uiradiobutton(app.ButtonGroup);
            app.MATButton.Text = 'MAT';
            app.LoadKinematicsButton = uibutton(app.LoadrobotkinematicsPanel, 'push');
            app.LoadKinematicsButton.Text = {'Load '; 'Kinematics'};
            app.TabGroup3 = uitabgroup(app.LoadrobotkinematicsPanel);
            app.TXTTab = uitab(app.TabGroup3);
            app.TXTTab.Title = 'TXT';
            app.FilenameEditFieldLabel = uilabel(app.TXTTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.TXTTab, 'text');
            app.MATTab = uitab(app.TabGroup3);
            app.MATTab.Title = 'MAT';
            app.TypeButtonGroup = uibuttongroup(app.MATTab);
            app.TypeButtonGroup.Title = 'Type';
            app.NoneButton = uiradiobutton(app.TypeButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.MinButton = uiradiobutton(app.TypeButtonGroup);
            app.MinButton.Text = 'Min';
            app.MaxButton = uiradiobutton(app.TypeButtonGroup);
            app.MaxButton.Text = 'Max';
            app.MedianButton = uiradiobutton(app.TypeButtonGroup);
            app.MedianButton.Text = 'Median';
            app.PertSpinnerLabel = uilabel(app.MATTab);
            app.PertSpinnerLabel.Text = 'Pert';
            app.PertSpinner = uispinner(app.MATTab);
            app.RepSpinnerLabel = uilabel(app.MATTab);
            app.RepSpinnerLabel.Text = 'Rep';
            app.RepSpinner = uispinner(app.MATTab);
            app.LoaddatasetPanel = uipanel(app.MainTab);
            app.LoaddatasetPanel.Title = 'Load dataset';
            app.DatasetparametersTextAreaLabel = uilabel(app.LoaddatasetPanel);
            app.DatasetparametersTextAreaLabel.Text = {'Dataset'; 'parameters'};
            app.DatasetparametersTextArea = uitextarea(app.LoaddatasetPanel);
            app.DatasetfunctionEditFieldLabel = uilabel(app.LoaddatasetPanel);
            app.DatasetfunctionEditFieldLabel.Text = {'Dataset'; 'function'};
            app.DatasetfunctionEditField = uieditfield(app.LoaddatasetPanel, 'text');
            app.LoadDatasetButton = uibutton(app.LoaddatasetPanel, 'push');
            app.LoadDatasetButton.Text = 'Load Dataset';
            app.VisualisationPanel = uipanel(app.MainTab);
            app.VisualisationPanel.Title = 'Visualisation';
            app.Button = uibutton(app.VisualisationPanel, 'push');
            app.Button_2 = uibutton(app.VisualisationPanel, 'push');
            app.Button_3 = uibutton(app.VisualisationPanel, 'push');
            app.Button_4 = uibutton(app.VisualisationPanel, 'push');
            app.CalibrationoptionsPanel = uipanel(app.MainTab);
            app.CalibrationoptionsPanel.Title = 'Calibration options';
            app.ApproachListBoxLabel = uilabel(app.CalibrationoptionsPanel);
            app.ApproachListBoxLabel.Text = 'Approach';
            app.ApproachListBox = uilistbox(app.CalibrationoptionsPanel);
            app.ApproachListBox.Items = {'selftouch', 'planes', 'external', 'projection'};
            app.ApproachListBox.Multiselect = 'on';
            app.ApproachListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChangedCallback, true);
            app.ApproachListBox.Tag = 'ApproachListBox';
            app.ApproachListBox.Value = {};
            app.jointTypesLabel = uilabel(app.CalibrationoptionsPanel);
            app.jointTypesLabel.Text = 'joint Types';
            app.jointTypesListBox = uilistbox(app.CalibrationoptionsPanel);
            app.jointTypesListBox.Items = {'joint', 'eye', 'torso', 'patch', 'triangle', 'mount', 'finger', 'taxel', 'onlyOffsets'};
            app.jointTypesListBox.Multiselect = 'on';
            app.jointTypesListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChangedCallback, true);
            app.jointTypesListBox.Tag = 'jointTypesListBox';
            app.jointTypesListBox.Value = {'joint', 'eye'};
            app.ChainsListBoxLabel = uilabel(app.CalibrationoptionsPanel);
            app.ChainsListBoxLabel.Text = 'Chains';
            app.ChainsListBox = uilistbox(app.CalibrationoptionsPanel);
            app.ChainsListBox.Items = {'rightArm', 'leftArm', 'torso', 'head', 'leftEye', 'rightEye', 'leftLeg', 'rightLeg', 'leftIndex', 'rightIndex', 'leftThumb', 'rightThumb', 'leftMiddle', 'rightMiddle'};
            app.ChainsListBox.Multiselect = 'on';
            app.ChainsListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChangedCallback, true);
            app.ChainsListBox.Tag = 'ChainsListBox';
            app.ChainsListBox.Value = {};
            app.CalibrationsettingsPanel_4 = uipanel(app.MainTab);
            app.CalibrationsettingsPanel_4.Title = 'Calibration settings';
            app.RuncalibrationButton = uibutton(app.CalibrationsettingsPanel_4, 'push');
            app.RuncalibrationButton.Text = 'Run calibration';
            app.SavejacobiansCheckBox = uicheckbox(app.CalibrationsettingsPanel_4);
            app.SavejacobiansCheckBox.Text = 'Save jacobians';
            app.SavedatasetCheckBox = uicheckbox(app.CalibrationsettingsPanel_4);
            app.SavedatasetCheckBox.Text = 'Save dataset';
            app.SavevarsCheckBox = uicheckbox(app.CalibrationsettingsPanel_4);
            app.SavevarsCheckBox.Text = 'Save vars';
            app.SavefoldernameEditFieldLabel = uilabel(app.CalibrationsettingsPanel_4);
            app.SavefoldernameEditFieldLabel.Text = 'Save folder name';
            app.SavefoldernameEditField = uieditfield(app.CalibrationsettingsPanel_4, 'text');
            app.TextArea = uitextarea(app.MainTab);
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.CalibrationoptionsPanel_2 = uipanel(app.ConfigTab);
            app.CalibrationoptionsPanel_2.Title = 'Calibration options';
            app.ApproachListBox_3Label = uilabel(app.CalibrationoptionsPanel_2);
            app.ApproachListBox_3Label.Text = 'Approach';
            app.ApproachListBox_3 = uilistbox(app.CalibrationoptionsPanel_2);
            app.ApproachListBox_3.Items = {'selftouch', 'planes', 'external', 'projection'};
            app.ApproachListBox_3.Multiselect = 'on';
            app.ApproachListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChangedCallback, true);
            app.ApproachListBox_3.Tag = 'ApproachListBox_3';
            app.ApproachListBox_3.Value = {};
            app.jointTypesListBox_3Label = uilabel(app.CalibrationoptionsPanel_2);
            app.jointTypesListBox_3Label.Text = 'joint Types';
            app.jointTypesListBox_3 = uilistbox(app.CalibrationoptionsPanel_2);
            app.jointTypesListBox_3.Items = {'joint', 'eye', 'torso', 'patch', 'triangle', 'mount', 'finger', 'taxel', 'onlyOffsets'};
            app.jointTypesListBox_3.Multiselect = 'on';
            app.jointTypesListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChangedCallback, true);
            app.jointTypesListBox_3.Tag = 'jointTypesListBox_3';
            app.jointTypesListBox_3.Value = {};
            app.ChainsListBox_3Label = uilabel(app.CalibrationoptionsPanel_2);
            app.ChainsListBox_3Label.Text = 'Chains';
            app.ChainsListBox_3 = uilistbox(app.CalibrationoptionsPanel_2);
            app.ChainsListBox_3.Items = {'rightArm', 'leftArm', 'torso', 'head', 'leftEye', 'rightEye', 'leftLeg', 'rightLeg', 'leftIndex', 'rightIndex', 'leftThumb', 'rightThumb', 'leftMiddle', 'rightMiddle'};
            app.ChainsListBox_3.Multiselect = 'on';
            app.ChainsListBox_3.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChangedCallback, true);
            app.ChainsListBox_3.Tag = 'ChainsListBox_3';
            app.ChainsListBox_3.Value = {};
            app.Panel = uipanel(app.ConfigTab);
            app.LoadConfigfileButton = uibutton(app.Panel, 'push');
            app.LoadConfigfileButton.Text = 'Load Config file';
            app.SaveConfigfileButton = uibutton(app.Panel, 'push');
            app.SaveConfigfileButton.Text = 'Save Config file';
            app.ReturntomainButton = uibutton(app.Panel, 'push');
            app.ReturntomainButton.Text = 'Return to main';
            app.PerturbationoptionsPanel = uipanel(app.ConfigTab);
            app.PerturbationoptionsPanel.Title = 'Perturbation options';
            app.UITable2_3 = uitable(app.PerturbationoptionsPanel);
            app.UITable2_3.ColumnName = {'x/a'; 'y/d'; 'z'; 'Alpha'; 'Beta'; 'Theta'};
            app.UITable2_3.RowName = {};
            app.OptimoptionsPanel = uipanel(app.ConfigTab);
            app.OptimoptionsPanel.Title = 'Optim options';
            app.UITable2 = uitable(app.OptimoptionsPanel);
            app.UITable2.ColumnName = {'Name'; 'Value'};
            app.UITable2.RowName = {};
            app.OptimizationoptionsPanel = uipanel(app.ConfigTab);
            app.OptimizationoptionsPanel.Title = 'Optimization options';
            app.UITable2_2 = uitable(app.OptimizationoptionsPanel);
            app.UITable2_2.ColumnName = {'Name'; 'Value'};
            app.UITable2_2.RowName = {};
            app.UITable2_2.ColumnEditable = true;
            app.RobotTab = uitab(app.TabGroup);
            app.RobotTab.Title = 'Robot';
            app.TabGroup2 = uitabgroup(app.RobotTab);
            app.JointsTab = uitab(app.TabGroup2);
            app.JointsTab.Title = 'Joints';
            app.Panel_2 = uipanel(app.JointsTab);
            app.LoadRobotfileButton = uibutton(app.Panel_2, 'push');
            app.LoadRobotfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRobotButtonPushed, true);
            app.LoadRobotfileButton.Text = 'Load Robot file';
            app.SaveRobotfileButton = uibutton(app.Panel_2, 'push');
            app.SaveRobotfileButton.Text = 'Save Robot file';
            app.ReturntomainButton_2 = uibutton(app.Panel_2, 'push');
            app.ReturntomainButton_2.Text = 'Return to main';
            app.NexttabButton = uibutton(app.Panel_2, 'push');
            app.NexttabButton.Text = 'Next tab';
            app.JointstructurePanel = uipanel(app.JointsTab);
            app.JointstructurePanel.Title = 'Joint structure';
            app.UITable4 = uitable(app.JointstructurePanel);
            app.UITable4.ColumnName = {'Joint name'; 'Joint type'; 'Parent'; 'Table index'; 'Joint group'};
            app.UITable4.RowName = {};
            app.UIAxes2 = uiaxes(app.JointstructurePanel);
            title(app.UIAxes2, {'Graph Model'; ''})
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.RobotnameEditFieldLabel = uilabel(app.JointsTab);
            app.RobotnameEditFieldLabel.Text = 'Robot name';
            app.RobotnameEditField = uieditfield(app.JointsTab, 'text');
            app.H0transformationmatrixPanel = uipanel(app.JointsTab);
            app.H0transformationmatrixPanel.Title = 'H0 transformation matrix';
            app.H0mat = uitable(app.H0transformationmatrixPanel);
            app.H0mat.ColumnName = '';
            app.H0mat.RowName = {};
            app.KinematicsTab = uitab(app.TabGroup2);
            app.KinematicsTab.Title = 'Kinematics';
            app.KinematicsparametersPanel = uipanel(app.KinematicsTab);
            app.KinematicsparametersPanel.Title = 'Kinematics parameters';
            app.UITable5 = uitable(app.KinematicsparametersPanel);
            app.UITable5.ColumnName = {'x/a'; 'y/d'; 'z'; 'Alpha'; 'Beta'; 'Theta'};
            app.UITable5.RowName = {};
            app.Panel_3 = uipanel(app.KinematicsTab);
            app.LoadRobotfileButton_2 = uibutton(app.Panel_3, 'push');
            app.LoadRobotfileButton_2.Text = 'Load Robot file';
            app.SaveRobotfileButton_2 = uibutton(app.Panel_3, 'push');
            app.SaveRobotfileButton_2.Text = 'Save Robot file';
            app.ReturntomainButton_3 = uibutton(app.Panel_3, 'push');
            app.ReturntomainButton_3.Text = 'Return to main';
            app.NexttabButton_2 = uibutton(app.Panel_3, 'push');
            app.NexttabButton_2.Text = 'Next tab';
            app.RobotdefaultconfigurationPanel = uipanel(app.KinematicsTab);
            app.RobotdefaultconfigurationPanel.Title = 'Robot default configuration';
            app.UITable_2 = uitable(app.RobotdefaultconfigurationPanel);
            app.UITable_2.ColumnName = {'Joint 1'; 'Joint 2'; 'Joint 3'; 'Joint 4'; 'Joint 5'; 'Joint 6'};
            app.UITable_2.RowName = {};
            app.GroupDropDown_2Label = uilabel(app.KinematicsTab);
            app.GroupDropDown_2Label.Text = 'Group';
            app.GroupDropDown_2 = uidropdown(app.KinematicsTab);
            app.GroupDropDown_2.Items = {'rightArm', 'leftArm', 'torso', 'head', 'leftEye', 'rightEye', 'leftLeg', 'rightLeg', 'leftIndex', 'rightIndex', 'leftThumb', 'rightThumb', 'leftMiddle', 'rightMiddle'};
            app.GroupDropDown_2.ValueChangedFcn = createCallbackFcn(app, @GroupDropDown_2ValueChanged, true);
            app.GroupDropDown_2.Value = 'rightArm';
            app.WhitelistBoundsTab = uitab(app.TabGroup2);
            app.WhitelistBoundsTab.Title = 'Whitelist + Bounds';
            app.Panel_4 = uipanel(app.WhitelistBoundsTab);
            app.LoadRobotfileButton_3 = uibutton(app.Panel_4, 'push');
            app.LoadRobotfileButton_3.Text = 'Load Robot file';
            app.SaveRobotfileButton_3 = uibutton(app.Panel_4, 'push');
            app.SaveRobotfileButton_3.Text = 'Save Robot file';
            app.ReturntomainButton_4 = uibutton(app.Panel_4, 'push');
            app.ReturntomainButton_4.Text = 'Return to main';
            app.NexttabButton_3 = uibutton(app.Panel_4, 'push');
            app.NexttabButton_3.Text = 'Next tab';
            app.GroupDropDown_3Label = uilabel(app.WhitelistBoundsTab);
            app.GroupDropDown_3Label.Text = 'Group';
            app.GroupDropDown_3 = uidropdown(app.WhitelistBoundsTab);
            app.GroupDropDown_3.Items = {'rightArm', 'leftArm', 'torso', 'head', 'leftEye', 'rightEye', 'leftLeg', 'rightLeg', 'leftIndex', 'rightIndex', 'leftThumb', 'rightThumb', 'leftMiddle', 'rightMiddle'};
            app.GroupDropDown_3.ValueChangedFcn = createCallbackFcn(app, @GroupDropDown_3ValueChanged, true);
            app.GroupDropDown_3.Value = 'rightArm';
            app.WhitelistPanel = uipanel(app.WhitelistBoundsTab);
            app.WhitelistPanel.Title = 'Whitelist';
            app.UITable5_2 = uitable(app.WhitelistPanel);
            app.UITable5_2.ColumnName = {'x/a'; 'y/d'; 'z'; 'Alpha'; 'Beta'; 'Theta'};
            app.UITable5_2.RowName = {};
            app.SavewhitelistButton = uibutton(app.WhitelistPanel, 'push');
            app.SavewhitelistButton.Text = 'Save whitelist';
            app.BoundsPanel = uipanel(app.WhitelistBoundsTab);
            app.BoundsPanel.Title = 'Bounds';
            app.UITable5_3 = uitable(app.BoundsPanel);
            app.UITable5_3.ColumnName = {'x/a'; 'y/d'; 'z'; 'Alpha'; 'Beta'; 'Theta'};
            app.UITable5_3.RowName = {};
            app.SaveboundsButton = uibutton(app.BoundsPanel, 'push');
            app.SaveboundsButton.Text = 'Save bounds';
            app.CameraseyesTab = uitab(app.TabGroup2);
            app.CameraseyesTab.Title = 'Cameras / eyes';
            app.Panel_5 = uipanel(app.CameraseyesTab);
            app.LoadRobotfileButton_4 = uibutton(app.Panel_5, 'push');
            app.LoadRobotfileButton_4.Text = 'Load Robot file';
            app.SaveRobotfileButton_4 = uibutton(app.Panel_5, 'push');
            app.SaveRobotfileButton_4.Text = 'Save Robot file';
            app.ReturntomainButton_5 = uibutton(app.Panel_5, 'push');
            app.ReturntomainButton_5.Text = 'Return to main';
            app.NexttabButton_4 = uibutton(app.Panel_5, 'push');
            app.NexttabButton_4.Text = 'Next tab';
            app.LefteyePanel = uipanel(app.CameraseyesTab);
            app.LefteyePanel.Title = 'Left eye';
            app.DistortioncoefficientsPanel = uipanel(app.LefteyePanel);
            app.DistortioncoefficientsPanel.Title = 'Distortion coefficients';
            app.tCoef1 = uitable(app.DistortioncoefficientsPanel);
            app.tCoef1.ColumnName = {'r1'; 'r2'};
            app.tCoef1.RowName = {};
            app.tangentialLabel = uilabel(app.DistortioncoefficientsPanel);
            app.tangentialLabel.Text = 'tangential';
            app.radialLabel = uilabel(app.DistortioncoefficientsPanel);
            app.radialLabel.Text = 'radial';
            app.rCoef1 = uitable(app.DistortioncoefficientsPanel);
            app.rCoef1.ColumnName = {'k1'; 'k2'; 'k3'; 'k4'; 'k5'; 'k6'};
            app.rCoef1.RowName = {};
            app.CameramatrixPanel = uipanel(app.LefteyePanel);
            app.CameramatrixPanel.Title = 'Camera matrix';
            app.camMatrix1 = uitable(app.CameramatrixPanel);
            app.camMatrix1.ColumnName = '';
            app.camMatrix1.RowName = {};
            app.RighteyePanel = uipanel(app.CameraseyesTab);
            app.RighteyePanel.Title = 'Right eye';
            app.DistortioncoefficientsPanel_2 = uipanel(app.RighteyePanel);
            app.DistortioncoefficientsPanel_2.Title = 'Distortion coefficients';
            app.tCoef2 = uitable(app.DistortioncoefficientsPanel_2);
            app.tCoef2.ColumnName = {'r1'; 'r2'};
            app.tCoef2.RowName = {};
            app.tangentialLabel_2 = uilabel(app.DistortioncoefficientsPanel_2);
            app.tangentialLabel_2.Text = 'tangential';
            app.radialLabel_2 = uilabel(app.DistortioncoefficientsPanel_2);
            app.radialLabel_2.Text = 'radial';
            app.rCoef2 = uitable(app.DistortioncoefficientsPanel_2);
            app.rCoef2.ColumnName = {'k1'; 'k2'; 'k3'; 'k4'; 'k5'; 'k6'};
            app.rCoef2.RowName = {};
            app.CameramatrixPanel_2 = uipanel(app.RighteyePanel);
            app.CameramatrixPanel_2.Title = 'Camera matrix';
            app.camMatrix2 = uitable(app.CameramatrixPanel_2);
            app.camMatrix2.ColumnName = '';
            app.camMatrix2.RowName = {};
            app.AdditionaleyePanel = uipanel(app.CameraseyesTab);
            app.AdditionaleyePanel.Title = 'Additional eye';
            app.DistortioncoefficientsPanel_3 = uipanel(app.AdditionaleyePanel);
            app.DistortioncoefficientsPanel_3.Title = 'Distortion coefficients';
            app.tCoef3 = uitable(app.DistortioncoefficientsPanel_3);
            app.tCoef3.ColumnName = {'r1'; 'r2'};
            app.tCoef3.RowName = {};
            app.tangentialLabel_3 = uilabel(app.DistortioncoefficientsPanel_3);
            app.tangentialLabel_3.Text = 'tangential';
            app.radialLabel_3 = uilabel(app.DistortioncoefficientsPanel_3);
            app.radialLabel_3.Text = 'radial';
            app.rCoef3 = uitable(app.DistortioncoefficientsPanel_3);
            app.rCoef3.ColumnName = {'k1'; 'k2'; 'k3'; 'k4'; 'k5'; 'k6'};
            app.rCoef3.RowName = {};
            app.CameramatrixPanel_3 = uipanel(app.AdditionaleyePanel);
            app.CameramatrixPanel_3.Title = 'Camera matrix';
            app.camMatrix3 = uitable(app.CameramatrixPanel_3);
            app.camMatrix3.ColumnName = '';
            app.camMatrix3.RowName = {};
            app.DatasetTab = uitab(app.TabGroup);
            app.DatasetTab.Title = 'Dataset';
            app.Panel_6 = uipanel(app.DatasetTab);
            app.LoadDatasetfileButton = uibutton(app.Panel_6, 'push');
            app.LoadDatasetfileButton.Text = 'Load Dataset file';
            app.SaveDatasetfileButton = uibutton(app.Panel_6, 'push');
            app.SaveDatasetfileButton.Text = 'Save Dataset file';
            app.ReturntomainButton_6 = uibutton(app.Panel_6, 'push');
            app.ReturntomainButton_6.Text = 'Return to main';
            app.DatasetinfoPanel = uipanel(app.DatasetTab);
            app.DatasetinfoPanel.Title = 'Dataset info';
            app.DatasetnameEditFieldLabel = uilabel(app.DatasetinfoPanel);
            app.DatasetnameEditFieldLabel.Text = 'Dataset name';
            app.DatasetnameEditField = uieditfield(app.DatasetinfoPanel, 'text');
            app.DatasetidEditFieldLabel = uilabel(app.DatasetinfoPanel);
            app.DatasetidEditFieldLabel.Text = 'Dataset id';
            app.DatasetidEditField = uieditfield(app.DatasetinfoPanel, 'numeric');
            app.DatasettypeDropDownLabel = uilabel(app.DatasetinfoPanel);
            app.DatasettypeDropDownLabel.Text = 'Dataset type';
            app.DatasettypeDropDown = uidropdown(app.DatasetinfoPanel);
            app.DatasettypeDropDown.Items = {'Selftouch', 'Planes', 'External', 'Projection', ''};
            app.DatasettypeDropDown.Value = 'Selftouch';
            app.JointsPanel = uipanel(app.DatasetinfoPanel);
            app.JointsPanel.Title = '# Joints';
            app.JointsDropDown = uidropdown(app.JointsPanel);
            app.JointsDropDown.Items = {'rightArm', 'leftArm', 'torso', 'head', 'leftEye', 'rightEye', 'leftLeg', 'rightLeg', 'leftIndex', 'rightIndex', 'leftThumb', 'rightThumb', 'leftMiddle', 'rightMiddle'};
            app.JointsDropDown.Value = 'rightArm';
            app.EditField_5 = uieditfield(app.JointsPanel, 'numeric');
            app.DataPanel = uipanel(app.DatasetTab);
            app.DataPanel.Title = 'Data';
            app.DatasetData = uitable(app.DataPanel);
            app.DatasetData.ColumnName = '';
            app.DatasetData.RowName = {};
            % Show the figure after all components are created
            app.Robot_toolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.Robot_toolboxUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.Robot_toolboxUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Robot_toolboxUIFigure)
        end
    end
end